classdef appOWAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        OWASAssessmentResultLabel  matlab.ui.control.Label
        ArmLabel                   matlab.ui.control.Label
        BackLabel                  matlab.ui.control.Label
        LegsLabel                  matlab.ui.control.Label
        LoadLabel                  matlab.ui.control.Label
        ACTIONLEVELLabel           matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        ImportanimageButton        matlab.ui.control.Button
        Label_6                    matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Property % Description
        O; C; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportanimageButton
        function ImportanimageButtonPushed(app, event)
            run('appCode.m')           
            app.O=imread('originImg.png');
            app.C=imread('cropImg.png');
            imshow(app.O, 'Parent', app.UIAxes);
            imshow(app.C, 'Parent', app.UIAxes_2);     
            
            localX1 = eval('app_back_post');
            localX2 = eval('app_arm_post');
            localX3 = eval('app_leg_post');
            localX4 = eval('app_force_weight');
            localX5 = eval('app_alevel');     
            localX7 = eval('file');
%             localX1 = eval('app_back_post');
            app.Label_5.Text = localX1;
            app.Label_4.Text = localX2;
            app.Label_3.Text = localX3;
            app.Label_2.Text = localX4;
            app.Label.Text = localX5;
            app.Label_6.Text = localX7;
            
            localX6 = eval('final_score');             
            if localX6 == 1
                app.Lamp.Color = [0.00,1.00,0.00];
            end
            if localX6 == 4
                app.Lamp.Color = [1.00,0.00,0.00];
            end
            if localX6 == 2
                app.Lamp.Color = [0.00,1.00,1.00];
            end
            if localX6 == 3
                app.Lamp.Color = [1.00,0.00,1.00];
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {304, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OWASAssessmentResultLabel = uilabel(app.LeftPanel);
            app.OWASAssessmentResultLabel.Text = 'OWAS Assessment Result';
            app.ArmLabel = uilabel(app.LeftPanel);
            app.ArmLabel.Text = 'Arm:';
            app.BackLabel = uilabel(app.LeftPanel);
            app.BackLabel.Text = 'Back:';
            app.LegsLabel = uilabel(app.LeftPanel);
            app.LegsLabel.Text = 'Legs:';
            app.LoadLabel = uilabel(app.LeftPanel);
            app.LoadLabel.Text = 'Load:';
            app.ACTIONLEVELLabel = uilabel(app.LeftPanel);
            app.ACTIONLEVELLabel.Text = 'ACTION LEVEL:';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '';
            app.Lamp = uilamp(app.LeftPanel);
            app.ImportanimageButton = uibutton(app.LeftPanel, 'push');
            app.ImportanimageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportanimageButtonPushed, true);
            app.ImportanimageButton.Icon = 'ico.png';
            app.ImportanimageButton.Text = 'Import an image';
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Initial Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Cropped Image')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appOWAS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end